classdef virt_moore_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        vIRtSimulationsUIFigure        matlab.ui.Figure
        FigTypesListBoxLabel           matlab.ui.control.Label
        RepsLabel                      matlab.ui.control.Label
        ParameterDropDownLabel         matlab.ui.control.Label
        VectorEditFieldLabel           matlab.ui.control.Label
        GraphicalUserInterfaceforvIRtSimulationsusingAdaptiveExponentialIntegrateandFireneuronsLabel  matlab.ui.control.Label
        LoadParametersButton           matlab.ui.control.Button
        ExpandAllButton                matlab.ui.control.Button
        ExpandTopButton                matlab.ui.control.Button
        CollapseAllButton              matlab.ui.control.Button
        ParameterTree                  matlab.ui.container.Tree
        MessageLabel                   matlab.ui.control.Label
        ParameterEditField             matlab.ui.control.NumericEditField
        ParameterDropDown              matlab.ui.control.DropDown
        StringEditField                matlab.ui.control.EditField
        StringEditFieldLabel           matlab.ui.control.Label
        VectorEditField1               matlab.ui.control.NumericEditField
        VectorEditField2               matlab.ui.control.NumericEditField
        ParameterCheckBox              matlab.ui.control.CheckBox
        EnableSquareWaveInputCheckBox  matlab.ui.control.CheckBox
        ToVaryCheckBox                 matlab.ui.control.CheckBox
        ToVaryEditField                matlab.ui.control.EditField
        TestNameEditField              matlab.ui.control.EditField
        FigTypesListBox                matlab.ui.control.ListBox
        RunOptionsPanel                matlab.ui.container.Panel
        ShowFiguresCheckBox            matlab.ui.control.CheckBox
        SaveOutputCheckBox             matlab.ui.control.CheckBox
        CopyScriptCheckBox             matlab.ui.control.CheckBox
        RandomizeSeedCheckBox          matlab.ui.control.CheckBox
        PlotSampleTracesCheckBox       matlab.ui.control.CheckBox
        PlotRasterCheckBox             matlab.ui.control.CheckBox
        PlotLogDecrementCheckBox       matlab.ui.control.CheckBox
        PlotAmpCorrelationCheckBox     matlab.ui.control.CheckBox
        PlotPhaseResponseCheckBox      matlab.ui.control.CheckBox
        ComputeWhiskAmpCheckBox        matlab.ui.control.CheckBox
        ComputeWhiskIntervalCheckBox   matlab.ui.control.CheckBox
        PlotAggregateOnlyCheckBox      matlab.ui.control.CheckBox
        AlwaysNewCheckBox              matlab.ui.control.CheckBox
        RunSimulationButton            matlab.ui.control.Button
        NRepetitionsEditField          matlab.ui.control.NumericEditField
        SaveParametersAsButton         matlab.ui.control.Button
        PulseTimesmsLabel              matlab.ui.control.Label
        PulseTimesTable                matlab.ui.control.Table
        AnalysisResultsTextArea        matlab.ui.control.TextArea
        vIRtRetractionNeuronSampleTracePanel  matlab.ui.container.Panel
        vIRtProtractionNeuronSampleTracePanel  matlab.ui.container.Panel
        EffectorTracePanel             matlab.ui.container.Panel
        AnalysisResultsTextAreaLabel   matlab.ui.control.Label
        TestNameEditFieldLabel         matlab.ui.control.Label
        ParameterEditFieldLabel        matlab.ui.control.Label
    end

    % Properties that store data
    properties (Access = private)
        Parameters struct % Stores the entire parameter structure (P)
        Handles    struct % Stores handles to graphics objects
        StateOld   struct % Stores the state structure from the last simulation
        ParamOld  struct % Stores the parameters from the last simulation run
        SelectedNode      % Stores the currently selected tree node
        ExpansionMap      % Tracks the expansion state of tree nodes
        strToChange = '';     % Stores the string to change in test name
        rangeToVary = [];     % Stores the numeric vector for parameter sweeps
        paramToVaryPath = ''; % Stores the path of the parameter being varied
    end

    % Custom functions
    methods (Access = private)
        
        % The following 3 functions are helper methods to recursively populate the tree
        function populate_tree(app, parentNode, dataStruct, prefix)
            % This function recursively traverses the parameter structure
            % and builds the tree in the GUI.

            % Get all field names
            fields = fieldnames(dataStruct);

            % Handle seedNumber as a special case to ensure it's always first
            if strcmp(prefix, '') % Check if it's the root level of the tree
                seedFieldName = 'seedNumber';
                isSeedPresent = ismember(seedFieldName, fields);

                if isSeedPresent
                    % Process the seedNumber field first
                    currentPrefix = [prefix, '.', seedFieldName];
                    [label, units] = app.get_parameter_info(seedFieldName, currentPrefix);
                    value = dataStruct.(seedFieldName);
                    
                    % Format the node text
                    if isempty(units)
                        nodeText = sprintf('%s: %g', label, value);
                    else
                        nodeText = sprintf('%s (%s): %g', label, units, value);
                    end
                    
                    % Create the node at the top level
                    uitreenode(parentNode, 'Text', nodeText, 'NodeData', currentPrefix);
                    
                    % Remove seedNumber from the list so it's not processed again
                    fields(strcmp(fields, seedFieldName)) = [];
                end
            end

            % Loop through all remaining field names
            for i = 1:length(fields)
                % Get the current field name
                fieldName = fields{i};

                % Skip the 'testName' field as it has its own dedicated edit box
                if strcmp(fieldName, 'testName')
                    continue;
                end

                % Update the current prefix
                currentPrefix = [prefix, '.', fieldName];

                % Get the descriptive label and units
                [label, units] = app.get_parameter_info(fieldName, currentPrefix);
                
                % If the field contains a nested structure, create a branch node
                if isstruct(dataStruct.(fieldName))
                    % Assign the path to the branch node's NodeData
                    node = uitreenode(parentNode, 'Text', label, 'NodeData', currentPrefix);

                    % Populate the branch node
                    app.populate_tree(node, dataStruct.(fieldName), currentPrefix);

                    % Continue to the next node
                    continue;
                end

                % Otherwise, get the current value
                value = dataStruct.(fieldName);

                % Show the value according to data type
                if isscalar(value) && (islogical(value) || isnumeric(value))
                    if islogical(value)
                        % Format for logicals (e.g., "Enable: true")
                        if value, valStr = 'true'; else, valStr = 'false'; end
                        nodeText = sprintf('%s: %s', label, valStr);
                    else
                        % Format for numerics (e.g., "Time Step (ms): 0.1")
                        if isempty(units)
                            nodeText = sprintf('%s: %g', label, value);
                        else
                            nodeText = sprintf('%s (%s): %g', label, units, value);
                        end
                    end
                elseif isnumeric(value) && isvector(value) && numel(value) == 2
                    % Handle 2-element numeric vectors
                    nodeText = sprintf('%s (%s): [%g, %g]', label, units, value(1), value(2));
                elseif ischar(value) || isstring(value)
                    nodeText = sprintf('%s: ''%s''', label, value);
                else
                    % For other data types not supported yet, just use the label
                    nodeText = label;
                end

                % Assign the path to the node's NodeData
                uitreenode(parentNode, 'Text', nodeText, 'NodeData', currentPrefix);
            end
        end

        function val = getfield_recursive(~, s, fieldPath)
            % Access nested structure fields using a path string (e.g., 'AdEx.Pb.Cm')
            parts = strsplit(fieldPath, '.');
            val = getfield(s, parts{2:end}); % Start from index 2 to skip initial '.'
        end
        
        function s = setfield_recursive(~, s, fieldPath, val)
            % Set nested structure fields using a path string
            parts = strsplit(fieldPath, '.');
            s = setfield(s, parts{2:end}, val); % Start from index 2
        end        

        % Find and update a node in the tree
        function update_tree_node_text(app, path, fieldName, valStr)
            allNodes = findall(app.ParameterTree, 'Type', 'uitreenode');
            for k = 1:length(allNodes)
                if strcmp(allNodes(k).NodeData, path)
                    % Get the descriptive label to ensure consistency
                    [label, ~] = app.get_parameter_info(fieldName, path);
                    allNodes(k).Text = sprintf('%s: %s', label, valStr);
                    break;
                end
            end
        end

        % Finds a node by its path and updates its text from app.Parameters
        % Note: Do not trigger callbacks here to avoid infinite recursion
        % with linked parameters
        function update_node_by_path(app, path)
            % Find the node with the matching data path
            allNodes = findall(app.ParameterTree, 'Type', 'uitreenode');
            nodeToUpdate = [];
            for k = 1:length(allNodes)
                if strcmp(allNodes(k).NodeData, path)
                    nodeToUpdate = allNodes(k);
                    break;
                end
            end
            
            % If the node was found in the tree
            if ~isempty(nodeToUpdate)
                % Get the new value from the master Parameters structure
                newValue = app.getfield_recursive(app.Parameters, path);
                
                % Get the field name from the path
                parts = strsplit(path, '.');
                fieldName = parts{end};
                
                % Get its descriptive label and units
                [label, units] = app.get_parameter_info(fieldName, path);
                
                % Format the text based on the data type
                if islogical(newValue)
                    if newValue, valStr = 'true'; else, valStr = 'false'; end
                    nodeText = sprintf('%s: %s', label, valStr);
                elseif isscalar(newValue) && isnumeric(newValue)
                    if isempty(units)
                        nodeText = sprintf('%s: %g', label, newValue);
                    else
                        nodeText = sprintf('%s (%s): %g', label, units, newValue);
                    end
                elseif isvector(newValue) && isnumeric(newValue) && numel(newValue) == 2
                    nodeText = sprintf('%s (%s): [%g, %g]', label, units, newValue(1), newValue(2));
                elseif ischar(newValue) || isstring(newValue)
                    nodeText = sprintf('%s: ''%s''', label, newValue);
                else
                    % This case should not be reached for editable parameters
                    nodeText = nodeToUpdate.Text; % Keep old text
                end
                
                % Set the node's text property
                nodeToUpdate.Text = nodeText;
            end
        end

        % Gets a descriptive label and units for a given parameter field name
        function [label, units] = get_parameter_info(~, fieldName, fullPath)
            % Set default fullpath if not provided
            if nargin < 3
                fullPath = '';
            end

            % Get the default label and units from the switch statement
            switch fieldName
                % Simulation Control
                case 'simDur'; label = 'Total simulation time'; units = 'ms';
                case 'dt'; label = 'Time step for Euler integration'; units = 'ms';
                case 'maxExpVal'; label = 'Maximum exponent value to prevent Inf/NaN'; units = '';
                case 'seedNumber'; label = 'Random Number Seed'; units = '';
                case 'rngAlgorithm'; label = 'RNG Algorithm'; units = '';

                % Neuron Pool Sizes
                case 'N'; label = '# of Neurons'; units = '';
                case 'Pb'; label = 'pre-Botzinger Neurons'; units = '';
                case 'Rr'; label = 'vIRt-retraction Neurons'; units = '';
                case 'Rp'; label = 'vIRt-protraction Neurons'; units = '';
                case 'Fm'; label = 'Facial Motor Neurons'; units = '';

                % AdEx Neuron Parameters
                case 'AdEx'; label = 'Neuron Parameters'; units = '';
                case 'Cm'; label = 'Membrane Capacitance'; units = 'pF';
                case 'gL'; label = 'Leak Conductance'; units = 'nS';
                case 'EL'; label = 'Leak Reversal Potential'; units = 'mV';
                case 'VT'; label = 'Spike Threshold'; units = 'mV';
                case 'DeltaT'; label = 'Slope Factor'; units = 'mV';
                case 'a'; label = 'Subthreshold Adaptation'; units = 'nS';
                case 'tauW'; label = 'Adaptation Time Constant'; units = 'ms';
                case 'b'; label = 'Spike-Triggered Adaptation'; units = 'pA';
                case 'Vreset'; label = 'Reset Potential'; units = 'mV';
                case 'Vspike'; label = 'Spike Detection Threshold'; units = 'mV';
                case 'TRef'; label = 'Refractory Period'; units = 'ms';

                % Synaptic Parameters
                case 'Synaptic'; label = 'Synaptic Parameters'; units = '';
                case 'tauSyn'; label = 'Synaptic Time Constant'; units = 'ms';
                
                % Connectivity
                case 'Connect'; label = 'Connection Probabilities'; units = '';
                case 'probPbToRr'; label = 'Prob(Pb -> Rr)'; units = '';
                case 'probRrToRp'; label = 'Prob(Rr -> Rp)'; units = '';
                case 'probRpToRr'; label = 'Prob(Rp -> Rr)'; units = '';
                case 'probRrToFm'; label = 'Prob(Rr -> Fm)'; units = '';
                case 'probRrIntra'; label = 'Prob(Rr -> Rr)'; units = '';
                case 'probRpIntra'; label = 'Prob(Rp -> Rp)'; units = '';
                
                % Synaptic Weights
                case 'ISynWeight'; label = 'Connection Weights'; units = '';
                case 'PbToRr'; label = 'Weight(Pb -> Rr)'; units = 'pA';
                case 'RrToRp'; label = 'Weight(Rr -> Rp)'; units = 'pA';
                case 'RpToRr'; label = 'Weight(Rp -> Rr)'; units = 'pA';
                case 'RrToFm'; label = 'Weight(Rr -> Fm)'; units = 'pA';
                case 'RrIntra'; label = 'Weight(Rr -> Rr)'; units = 'pA';
                case 'RpIntra'; label = 'Weight(Rp -> Rp)'; units = 'pA';
                case 'Variability'; label = 'Weight Variability'; units = '%';

                % External Currents
                case 'ExtCurrent'; label = 'External Current'; units = ''; % No units for the parent struct

                % Square Wave Input
                case 'SquareInput'; label = 'Square Wave Input'; units = '';
                case 'enable'; label = 'Enable'; units = '';
                case 'baseline'; label = 'Baseline'; units = 'pA';
                case 'amplitude'; label = 'Amplitude'; units = 'pA';
                case 'relativeTransitionTime'; label = 'Relative Transition Time'; units = '';
                case 'period1'; label = 'Period 1 (1st half)'; units = 'ms';
                case 'perVar1'; label = 'Period Variability 1'; units = 'ms';
                case 'perVarPerc1'; label = 'Period Variability 1'; units = '%';
                case 'period2'; label = 'Period 2 (2nd half)'; units = 'ms';
                case 'perVar2'; label = 'Period Variability 2'; units = 'ms';
                case 'perVarPerc2'; label = 'Period Variability 2'; units = '%';
                case 'pulseWidth'; label = 'Pulse Width'; units = 'ms';

                % Effector
                case 'Effector'; label = 'Effector Parameters'; units = '';
                case 'tauAlpha'; label = 'Alpha Time Constant'; units = 'ms';
                case 'XperSpike'; label = 'Contribution per Spike'; units = 'a.u.';

                % Analysis Parameters
                case 'Analysis'; label = 'Analysis Parameters'; units = '';
                case 'relativeAnalysisStart'; label = 'Relative Analysis Start'; units = '';
                case 'amplitudeDefinition'; label = 'Amplitude Definition'; units = '';
                case 'fundFreqRange'; label = 'Fundamental Freq Range'; units = 'Hz';
                case 'fCutoffWhisk'; label = 'Whisk Filter Cutoff'; units = 'Hz';
                case 'fCutoffRelToFund'; label = 'Filter Cutoff Relative to Fund'; units = '';
                case 'filterOrderWhisk'; label = 'Whisk Filter Order'; units = '';
                case 'promThresholdPercWhisk'; label = 'Min Peak Prominence'; units = '%';
                case 'minPeakPromWhisk'; label = 'Min Peak Prominence'; units = 'deg';
                case 'maxWhiskDurationMs'; label = 'Max Whisk Duration'; units = 'ms';
                case 'minPeakDistanceMsWhisk'; label = 'Min Peak Distance'; units = 'ms';
                case 'sniffFreqThreshold'; label = 'Sniff Frequency Threshold'; units = 'Hz';
                case 'basalFreqThreshold'; label = 'Basal Frequency Threshold'; units = 'Hz';
                case 'nWhisksSniffStartToAnalyze'; label = '# Whisks to Analyze (Sniff)'; units = '';
                case 'minWhisksBasalRespToAnalyze'; label = 'Min # Whisks to Analyze (Basal)'; units = '';
                case 'maxWhisksBasalRespToAnalyze'; label = 'Max # Whisks to Analyze (Basal)'; units = '';
                case 'nCorrToAnalyze'; label = '# Correlations to Analyze'; units = '';
                case 'whiskDirForPhase'; label = 'Whisk Direction for Phase'; units = '';
                case 'breathOnsetLatencyMs'; label = 'Breath Onset Latency'; units = 'ms';

                % Plotting Parameters
                case 'Plotting'; label = 'Plotting Parameters'; units = '';
                case 'sampleDuration'; label = 'Sample Plot Duration'; units = 's';
                case 'ILimSample'; label = 'Sample I-Trace Y-Limits'; units = 'pA';
                case 'vLimSample'; label = 'Sample V-Trace Y-Limits'; units = 'mV';
                case 'eLimSample'; label = 'Sample Effector Y-Limits'; units = 'a.u.';
                case 'vMarkerChosenSpikes'; label = 'Spike Marker Voltage'; units = 'mV';
                case 'colorPb'; label = 'Pb Neuron Color'; units = '';
                case 'colorRr'; label = 'Rr Neuron Color'; units = '';
                case 'colorRp'; label = 'Rp Neuron Color'; units = '';
                case 'colorFm'; label = 'Fm Neuron Color'; units = '';
                case 'markerTypePeak'; label = 'Peak Marker Type'; units = '';
                case 'colorFirstPeakUsed'; label = '1st Peak Color (Used)'; units = '';
                case 'colorFirstPeakNotUsed'; label = '1st Peak Color (Not Used)'; units = '';
                case 'colorOtherPeak'; label = 'Other Peak Color'; units = '';
                case 'colorValley'; label = 'Valley Color'; units = '';
                case 'lineStylePeakAmplitude'; label = 'Peak Amplitude Line Style'; units = '';
                case 'colorPeakAmplitude'; label = 'Peak Amplitude Color'; units = '';
                case 'colorEffectorOverlay'; label = 'Effector Overlay Color'; units = '';
                case 'colorBasalCycleShade'; label = 'Basal Cycle Shade Color'; units = '';
                case 'colorSniffStartWin'; label = 'Sniff Window Shade Color'; units = '';
                case 'colorPreAnalysisShade'; label = 'Pre-Analysis Shade Color'; units = '';
                case 'colorT0'; label = 'T0 Bar Color'; units = '';
                case 'colorT1'; label = 'T1 Bar Color'; units = '';
                case 'colorReset'; label = 'Reset Bar Color'; units = '';
                case 'barFaceAlpha'; label = 'Phase Bar Alpha'; units = '';
                case 'transparencyOverlay'; label = 'Effector Overlay Alpha'; units = '';
                case 'transparencyBasalCycle'; label = 'Basal Cycle Shade Alpha'; units = '';
                case 'transparencySniffStartWin'; label = 'Sniff Window Shade Alpha'; units = '';
                case 'transparencyPreAnalysisShade'; label = 'Pre-Analysis Shade Alpha'; units = '';
                case 'markerSizeRaster'; label = 'Raster Marker Size'; units = 'pts';
                case 'markerSizeFirstPeak'; label = '1st Peak Marker Size'; units = 'pts';
                case 'markerSizeOtherPeak'; label = 'Other Peak Marker Size'; units = 'pts';
                case 'markerSizeValley'; label = 'Valley Marker Size'; units = 'pts';
                case 'markerSizeChosenSpikes'; label = 'Spike Marker Size'; units = 'pts';
                case 'lineWidthSampleTraces'; label = 'Sample Trace Line Width'; units = 'pts';
                case 'lineWidthOverlay'; label = 'Effector Overlay Line Width'; units = 'pts';
                case 'lineWidthChosenSpikes'; label = 'Spike Marker Line Width'; units = 'pts';
                case 'markerSizeJitter'; label = 'Jitter Plot Marker Size'; units = 'pts';
                case 'jitterWidth'; label = 'Jitter Plot Width'; units = '';
                case 'colorScatter'; label = 'Scatter Plot Color'; units = '';
                case 'markerTypeScatter'; label = 'Scatter Plot Marker Type'; units = '';
                case 'markerSizeScatter'; label = 'Scatter Plot Marker Size'; units = 'pts';
                case 'markerLineWidthScatter'; label = 'Scatter Marker Line Width'; units = 'pts';
                case 'colorBestFit'; label = 'Best-Fit Line Color'; units = '';
                case 'lineStyleBestFit'; label = 'Best-Fit Line Style'; units = '';
                case 'lineWidthBestFit'; label = 'Best-Fit Line Width'; units = 'pts';
                case 'colorThrOrig'; label = 'Through Origin Line Color'; units = '';
                case 'lineStyleThrOrig'; label = 'Through Origin Line Style'; units = '';
                case 'lineWidthThrOrig'; label = 'Through Origin Line Width'; units = 'pts';

                % Default Case for unknown parameters
                otherwise
                    % Convert camelCase to Title Case for readability
                    label = regexprep(fieldName, '([A-Z])', ' $1');
                    label = [upper(label(1)), label(2:end)];
                    units = '';
            end

            % Context-specific overrides
            if startsWith(fullPath, '.ExtCurrent.')
                units = 'pA'; % Override units for all fields under ExtCurrent
            end
        end

        % This function updates the text area for pulse times
        function update_pulse_times_display(app)
            % Check if the necessary fields exist
            if isfield(app.Parameters, 'SquareInput') && ...
               isfield(app.Parameters.SquareInput, 'Pb') && ...
               isfield(app.Parameters.SquareInput.Pb, 'timesStart')
           
                timesStart = app.Parameters.SquareInput.Pb.timesStart;
                timesEnd = app.Parameters.SquareInput.Pb.timesEnd;
                
                % Create the pulse number column
                numPulses = length(timesStart);
                pulseNumbers = (1:numPulses)';
                
                % --- NEW: Convert numeric data to formatted strings ---
                % Use arrayfun and sprintf to format each column as text
                colPulseNum = arrayfun(@(x) sprintf('%d', x), pulseNumbers, 'UniformOutput', false);
                colStart = arrayfun(@(x) sprintf('%.1f', x), timesStart, 'UniformOutput', false);
                colEnd = arrayfun(@(x) sprintf('%.1f', x), timesEnd, 'UniformOutput', false);
                
                % Combine the cell arrays of strings into the final table data
                tableData = [colPulseNum, colStart, colEnd];
                
                % Update the table
                app.PulseTimesTable.Data = tableData;
                app.PulseTimesTable.Visible = 'on';
                app.PulseTimesmsLabel.Visible = 'on';
            else
                % If fields don't exist, hide the table
                app.PulseTimesTable.Data = [];
                app.PulseTimesTable.Visible = 'off';
                app.PulseTimesmsLabel.Visible = 'off';
            end
        end

        % This function updates the text area when pulse times are stale
        function invalidate_pulse_times_display(app)
            % When parameters change, the old times are invalid.
            % Clear the table and post a message.
            app.PulseTimesTable.Data = [];
            app.PulseTimesTable.Visible = 'off';
            app.PulseTimesmsLabel.Visible = 'off';
            app.MessageLabel.Text = 'Pulse times will be recomputed on next run.';
        end

        % This function updates the message label text
        function update_message(app, message)
            % Set the label text in GUI
            app.MessageLabel.Text = message;

            % Force the GUI to refresh
            drawnow;
        end

        % Saves the current expansion and selection state of the tree
        function treeState = save_tree_state(app)
            treeState = struct();
            
            % Save the path of the currently selected node
            selectedNodes = app.ParameterTree.SelectedNodes;
            if ~isempty(selectedNodes)
                treeState.SelectedNodePath = selectedNodes(1).NodeData;
            else
                treeState.SelectedNodePath = '';
            end
            
            % Save the expansion map
            treeState.ExpansionMap = app.ExpansionMap;
        end

        % Restores the expansion and selection state to the tree
        function restore_tree_state(app, treeState)
            if isempty(treeState) || ~isfield(treeState, 'ExpansionMap')
                collapse(app.ParameterTree, 'all');
                return;
            end
            
            app.ExpansionMap = treeState.ExpansionMap;
            allNewNodes = findall(app.ParameterTree, 'Type', 'uitreenode');
            
            % Restore expansion state from the map
            for i = 1:length(allNewNodes)
                nodePath = allNewNodes(i).NodeData;
                if isKey(app.ExpansionMap, nodePath) && app.ExpansionMap(nodePath) == true
                    expand(allNewNodes(i));
                end
            end
            
            % Restore selection state
            if isfield(treeState, 'SelectedNodePath') && ~isempty(treeState.SelectedNodePath)
                selectedPath = treeState.SelectedNodePath;
                for i = 1:length(allNewNodes)
                    if strcmp(allNewNodes(i).NodeData, selectedPath)
                        % Found the node, select it
                        app.ParameterTree.SelectedNodes = allNewNodes(i);
                        
                        % Manually trigger the selection callback to update
                        % the edit field
                        app.ParameterTreeSelectionChanged([]);
                        break;
                    end
                end
            end
        end

        % Cleans up a parameter name
        function cleanParamName = clean_param_name(~, paramPath)
            % Split the path into parts
            parts = strsplit(paramPath, '.');
    
            % Get the param name to place in test name field
            %   Since the first part is '' this will start with '-'
            cleanParamName = strjoin(parts(1:end), '-');

            % Abbreviate common parameter names for a shorter test name
            cleanParamName = replace(cleanParamName, '-Analysis', '');
            cleanParamName = replace(cleanParamName, '-Plotting', '');
            cleanParamName = replace(cleanParamName, '-Connect', '');
            cleanParamName = replace(cleanParamName, '-AdEx', '');
            cleanParamName = replace(cleanParamName, '-Synaptic', '');
            cleanParamName = replace(cleanParamName, '-ISynWeight', '-wt');
            cleanParamName = replace(cleanParamName, '-Variability', '-Var');
            cleanParamName = replace(cleanParamName, '-ExtCurrent', '-IExt');
            cleanParamName = replace(cleanParamName, '-SquareInput-Pb', '-SqInpt');
        end

        % Updates the Test Name field based on parameter or sweep changes
        function update_test_name(app, paramPath, newValue)
            % Get the old name from the edit field
            oldName = app.TestNameEditField.Value;
            
            % If the current name is the same as the last run's name,
            % use 'Modified' as a fresh base to avoid overly long names.
            % Note: this may not be helpful anymore now that 
            %       parameter-value pairs can be replaced
            % if isfield(app.ParamOld, 'testName') && ...
            %    isstruct(app.ParamOld) && ~isempty(app.ParamOld) && ...
            %    strcmp(oldName, app.ParamOld.testName)
            %     oldName = 'Modified';
            % end

            % Handle different update types
            if strcmp(paramPath, 'nReps')
                % Case for updating the number of repetitions
                paramKey = '-nReps';
                valStr = sprintf('%d', newValue);
            else
                % Case for updating a regular parameter or defining a sweep

                % Get a cleaned up parameter name
                paramKey = app.clean_param_name(paramPath);
                
                % Format the value part of the string based on its type
                if isempty(newValue)
                    % Make this an empty string so that the param-value
                    % pair will be removed
                    valStr = '';
                elseif ischar(newValue) || isstring(newValue)
                    % This handles both regular strings and the sweep expression
                    valStr = char(newValue);
                elseif isnumeric(newValue) && numel(newValue) <= 2
                    if isscalar(newValue)
                        valStr = sprintf('%g', newValue);
                    else
                        valStr = sprintf('%gto%g', newValue(1), newValue(2));
                    end
                else
                    valStr = 'changed';        
                end
            end

            % Clean up characters that are bad for filenames/readability
            valStr = strrep(valStr, ':', 'to');
            valStr = strrep(valStr, ' ', '');
            valStr = strrep(valStr, './', 'div');
            valStr = strrep(valStr, '-', 'minus');
            valStr = strrep(valStr, '/', 'div');
            valStr = regexprep(valStr, '[\[\]\(\)]', '');
            
            % The full new key-value segment to be replaced or appended
            if ~isempty(valStr)
                addition = [paramKey, '-', valStr];
            else
                addition = '';
            end

            % Save to the internal string for parameter to change
            app.strToChange = addition;
            
            % Create a regular expression to find an existing entry for this parameter key.
            % It matches "-<key>-" followed by one or more characters that are not a hyphen.
            pattern = [paramKey, '-[^-]+'];
            
            if regexp(oldName, pattern)
                % If the key already exists, replace the old segment
                newName = regexprep(oldName, pattern, addition);
            else
                % If the key doesn't exist, append the new segment
                newName = [oldName, addition];
            end
            
            % Update both the GUI field and the internal P.testName parameter
            app.TestNameEditField.Value = newName;
            app.Parameters.testName = newName;
        end

        % Create name-value pair cell array of run options
        function opts = get_run_options(app)
            opts = {'SaveOutput', app.SaveOutputCheckBox.Value, ...
                    'CopyScript', app.CopyScriptCheckBox.Value, ...
                    'RandomizeSeed', app.RandomizeSeedCheckBox.Value, ...
                    'PlotSampleTraces', app.PlotSampleTracesCheckBox.Value, ...
                    'PlotRaster', app.PlotRasterCheckBox.Value, ...
                    'PlotJitter', app.PlotLogDecrementCheckBox.Value, ...
                    'PlotScatter', app.PlotAmpCorrelationCheckBox.Value, ...
                    'PlotPhaseResponse', app.PlotPhaseResponseCheckBox.Value, ...
                    'PlotAggregateOnly', app.PlotAggregateOnlyCheckBox.Value, ...
                    'ComputeWhiskAmp', app.ComputeWhiskAmpCheckBox.Value, ...
                    'ComputeWhiskInterval', app.ComputeWhiskIntervalCheckBox.Value, ...
                    'ShowFigure', app.ShowFiguresCheckBox.Value, ...
                    'AlwaysNew', app.AlwaysNewCheckBox.Value, ...
                    'FigTypes', app.FigTypesListBox.Value};
        end

        % Update GUI components after a run completes
        function update_gui_after_run(app, newTestName, analysisText, newHandles, treeState)
            % Refresh the parameter tree
            app.ParameterTree.Children.delete;
            app.populate_tree(app.ParameterTree, app.Parameters, '');

            % Restore Tree State
            if ~isempty(treeState)
                app.restore_tree_state(treeState);
            else
                collapse(app.ParameterTree, 'all');
            end
            
            % Update the Test Name
            app.TestNameEditField.Value = newTestName;

            % Update the pulse times display with any new values
            app.update_pulse_times_display();
        
            % Update plots and analysis text area
            update_gui_plots(app, newHandles, analysisText);
        end

        % Helper function to update plots on the GUI
        function update_gui_plots(app, updatedHandles, analysisText)
            % Update the analysis text display or hide 
            if ~isempty(analysisText)
                app.AnalysisResultsTextArea.Value = analysisText;
                app.AnalysisResultsTextArea.Visible = 'on';
                app.AnalysisResultsTextAreaLabel.Visible = 'on';
            else
                app.AnalysisResultsTextArea.Visible = 'off';
                app.AnalysisResultsTextAreaLabel.Visible = 'off';
            end
        
            % Update the vIRt Retraction (Rr) sample trace plot
            delete(app.vIRtRetractionNeuronSampleTracePanel.Children);
            if ~isempty(updatedHandles) && isfield(updatedHandles, 'ax1') && ...
                    numel(updatedHandles.ax1) >= 3 && isgraphics(updatedHandles.ax1(3))
                sourceAxes = updatedHandles.ax1(3);
                copiedAxes = copyobj(sourceAxes, app.vIRtRetractionNeuronSampleTracePanel);
                copiedAxes.Title.String = 'vIRt Retraction Neuron #1 Trace (close-up)';
                copiedAxes.XLabel.String = 'Time (s)';
                copiedAxes.Units = 'normalized';
                inset = copiedAxes.TightInset;
                copiedAxes.Position = [inset(1), inset(2), 1-inset(1)-inset(3), 1-inset(2)-inset(4)];
                app.vIRtRetractionNeuronSampleTracePanel.Title = '';
                app.vIRtRetractionNeuronSampleTracePanel.Visible = 'on';
            else
                app.vIRtRetractionNeuronSampleTracePanel.Visible = 'off';
            end
    
            % Update the vIRt Protraction (Rp) sample trace plot
            delete(app.vIRtProtractionNeuronSampleTracePanel.Children);
            if ~isempty(updatedHandles) && isfield(updatedHandles, 'ax1') && ...
                    numel(updatedHandles.ax1) >= 4 && isgraphics(updatedHandles.ax1(4))
                sourceAxes = updatedHandles.ax1(4);
                copiedAxes = copyobj(sourceAxes, app.vIRtProtractionNeuronSampleTracePanel);
                copiedAxes.Title.String = 'vIRt Protraction Neuron #1 Trace (close-up)';
                copiedAxes.XLabel.String = 'Time (s)';
                copiedAxes.Units = 'normalized';
                inset = copiedAxes.TightInset;
                copiedAxes.Position = [inset(1), inset(2), 1-inset(1)-inset(3), 1-inset(2)-inset(4)];
                app.vIRtProtractionNeuronSampleTracePanel.Title = '';
                app.vIRtProtractionNeuronSampleTracePanel.Visible = 'on';
            end
    
            % Update the effector plot by copying the axes
            delete(app.EffectorTracePanel.Children); % Clear old plot
            if ~isempty(updatedHandles) && isfield(updatedHandles, 'ax2') && ...
                    numel(updatedHandles.ax2) >= 2 && isgraphics(updatedHandles.ax2(2))
                
                sourceAxes = updatedHandles.ax2(2);
    
                % Copy the axes from the simulation figure to the GUI panel
                copiedAxes = copyobj(sourceAxes, app.EffectorTracePanel);
                
                % Set the title on the axes, not the panel
                copiedAxes.Title.String = 'Whisk Position Trace with Pb Input Shaded (full)';
                
                % Set units to normalized for automatic resizing
                copiedAxes.Units = 'normalized';
                
                % Get the margins required by the labels and title
                inset = copiedAxes.TightInset;
                
                % Calculate the new position to fit everything
                newPosition = [inset(1), inset(2), 1 - inset(1) - inset(3), 1 - inset(2) - inset(4)];
                copiedAxes.Position = newPosition;
    
                % Manually copy other properties to ensure they appear
                copiedAxes.XLabel.String = sourceAxes.XLabel.String;
                copiedAxes.YLabel.String = sourceAxes.YLabel.String;
                copiedAxes.XLim = sourceAxes.XLim;
                copiedAxes.YLim = sourceAxes.YLim;
                copiedAxes.XGrid = sourceAxes.XGrid;
                copiedAxes.YGrid = sourceAxes.YGrid;
                
                % Remove the panel's title to prevent overlap
                app.EffectorTracePanel.Title = '';
                app.EffectorTracePanel.Visible = 'on';
            else
                app.EffectorTracePanel.Visible = 'off';
            end
        end

        % Method to reset the parameter sweep state
        function reset_to_vary_state(app, keepBoxShown)
            % If a parameter sweep was active, update the test name.
            if ~isempty(app.paramToVaryPath)
                % Get the current value of the parameter that was being varied.
                currentValue = app.getfield_recursive(app.Parameters, app.paramToVaryPath);
    
                % Default to assuming the value has not changed from the last run.
                isSameAsLastRun = false;
    
                % Check if ParamOld exists and has the parameter field.
                if ~isempty(app.ParamOld)
                    try
                        % Attempt to get the value from the last simulation run.
                        oldValue = app.getfield_recursive(app.ParamOld, app.paramToVaryPath);
                        % If the current value is the same as the last run, mark it.
                        if isequal(currentValue, oldValue)
                            isSameAsLastRun = true;
                        end
                    catch
                        % If the field doesn't exist in ParamOld, it's treated as a new change.
                        isSameAsLastRun = false;
                    end
                end
    
                % If the value is the same as the last run, remove the parameter-value
                % pair from the test name, as it's part of the new baseline.
                % Otherwise, update the test name to reflect the current single-point value,
                % thus preserving a change that was made before the sweep was cancelled.
                if isSameAsLastRun
                    app.update_test_name(app.paramToVaryPath, []);
                else
                    app.update_test_name(app.paramToVaryPath, currentValue);
                end
    
                % Always remove the nReps part when a parameter sweep is cancelled.
                app.update_test_name('nReps', []);
            end
    
            % Hides the 'To Vary' controls and resets internal state variables.
            app.ToVaryCheckBox.Value = false;
            if ~keepBoxShown
                app.ToVaryCheckBox.Visible = 'off';
            end
            app.ToVaryEditField.Visible = 'off';
            app.ToVaryEditField.Value = '';
            app.rangeToVary = [];
            app.paramToVaryPath = '';
            app.NRepetitionsEditField.Value = 1;
            app.NRepetitionsEditField.Enable = 'on';
        end
    
        % Method to update a parameter's value
        function update_parameter_value(app, paramPath, newValue, oldValue)
            % This method updates the internal struct, the GUI tree,
            % and handles all linked-parameter side effects.

            % Invalidate state if a non-analysis/non-plotting parameter is changed
            if ~startsWith(paramPath, '.Analysis.') && ~startsWith(paramPath, '.Plotting.')
                app.StateOld = struct.empty;
                app.MessageLabel.Text = 'Simulation parameter changed. Re-run required.';
            elseif startsWith(paramPath, '.Analysis.')
                app.MessageLabel.Text = 'Analysis parameter changed. Re-run to update plots.';
            elseif startsWith(paramPath, '.Plotting.')
                app.MessageLabel.Text = 'Plotting parameter changed. Re-run to update plots.';
            end

            % Update the parameter both internally and on the tree
            app.Parameters = app.setfield_recursive(app.Parameters, paramPath, newValue);
            app.update_node_by_path(paramPath);

            % If a square wave parameter changed, update its linked parameters
            if startsWith(paramPath, '.SquareInput.Pb.')
                % Get the specific field that was changed
                parts = strsplit(paramPath, '.');
                fieldName = parts{end};

                % Handle bi-directional updates
                switch fieldName
                    case 'period1'
                        % Update perVar1 based on the current percentage
                        currentPerc = app.Parameters.SquareInput.Pb.perVarPerc1;
                        newPerVar = (currentPerc / 100) * newValue;
                        app.Parameters.SquareInput.Pb.perVar1 = newPerVar;
                        app.update_node_by_path('.SquareInput.Pb.perVar1');
                    case 'perVar1'
                        % Update perVarPerc1 based on the current period
                        currentPeriod = app.Parameters.SquareInput.Pb.period1;
                        if currentPeriod > 0
                            newPerc = (newValue / currentPeriod) * 100;
                            app.Parameters.SquareInput.Pb.perVarPerc1 = newPerc;
                            app.update_node_by_path('.SquareInput.Pb.perVarPerc1');
                        end
                    case 'perVarPerc1'
                        % Update perVar1 based on the current period
                        currentPeriod = app.Parameters.SquareInput.Pb.period1;
                        newPerVar = (newValue / 100) * currentPeriod;
                        app.Parameters.SquareInput.Pb.perVar1 = newPerVar;
                        app.update_node_by_path('.SquareInput.Pb.perVar1');
                    case 'period2'
                        % Update perVar2 based on the current percentage
                        currentPerc = app.Parameters.SquareInput.Pb.perVarPerc2;
                        newPerVar = (currentPerc / 100) * newValue;
                        app.Parameters.SquareInput.Pb.perVar2 = newPerVar;
                        app.update_node_by_path('.SquareInput.Pb.perVar2');
                    case 'perVar2'
                        % Update perVarPerc2 based on the current period
                        currentPeriod = app.Parameters.SquareInput.Pb.period2;
                        if currentPeriod > 0
                            newPerc = (newValue / currentPeriod) * 100;
                            app.Parameters.SquareInput.Pb.perVarPerc2 = newPerc;
                            app.update_node_by_path('.SquareInput.Pb.perVarPerc2');
                        end
                    case 'perVarPerc2'
                        % Update perVar2 based on the current period
                        currentPeriod = app.Parameters.SquareInput.Pb.period2;
                        newPerVar = (newValue / 100) * currentPeriod;
                        app.Parameters.SquareInput.Pb.perVar2 = newPerVar;
                        app.update_node_by_path('.SquareInput.Pb.perVar2');
                end
            end

            % If an Rr parameter changed, update the corresponding Rp parameter proportionally
            if contains(paramPath, 'Rr') && ~contains(paramPath, 'RpToRr') && ...
               ~contains(paramPath, 'PbToRr') && ~contains(paramPath, 'RrToFm')
                % Construct the corresponding Rp path
                if contains(paramPath, 'RrToRp')
                    rpPath = strrep(paramPath, 'RrToRp', 'RpToRr');
                else
                    rpPath = strrep(paramPath, 'Rr', 'Rp');
                end
                
                % Check if the corresponding Rp parameter exists before proceeding
                try
                    % Get the original value of the corresponding Rp parameter
                    oldRpValue = app.getfield_recursive(app.Parameters, rpPath);
                    
                    % Calculate the ratio based on the old Rr and Rp values
                    if oldValue ~= 0
                        ratio = oldRpValue / oldValue;
                    else
                        ratio = 1; % Default ratio if old value was 0
                    end
                    
                    % Calculate and apply the new Rp value and update in the tree
                    newRpValue = newValue * ratio;
                    app.Parameters = app.setfield_recursive(app.Parameters, rpPath, newRpValue);
                    app.update_node_by_path(rpPath);
                catch
                    % If rpPath does not exist, do nothing (e.g., for PbToRr and RrtoFm)
                end
            end

            % Synchronize Pb external mean current and square wave amplitude
            if strcmp(paramPath, '.SquareInput.Pb.amplitude')
                % If amplitude was changed, update the external current
                app.Parameters.ExtCurrent.Pb.mean = newValue;
                app.update_node_by_path('.ExtCurrent.Pb.mean');
            elseif strcmp(paramPath, '.ExtCurrent.Pb.mean')
                % If external current was changed, update the amplitude
                app.Parameters.SquareInput.Pb.amplitude = newValue;
                app.update_node_by_path('.SquareInput.Pb.amplitude');
            end

            % Update toRecompute flag if simulation duration
            %   changed or if PB input settings changed,
            %   hide previously computed pulse times and update in the tree
            if strcmp(paramPath, '.simDur') || startsWith(paramPath, '.SquareInput.Pb.')                        
                if isfield(app.Parameters.SquareInput.Pb, 'toRecompute')
                    app.Parameters.SquareInput.Pb.toRecompute = true;
                    app.invalidate_pulse_times_display();
                    app.update_node_by_path('.SquareInput.Pb.toRecompute');
                end
            end

            % Append the change to the test name
            app.update_test_name(paramPath, newValue);
        end

        % Function to robustly wait for figures to finish rendering and processing
        function wait_for_figures(~, figHandlesStruct)
            % This function iterates through a structure of graphics handles,
            % finds all figure handles, and waits for them to signal completion.
            
            if isempty(figHandlesStruct) || ~isstruct(figHandlesStruct)
                return; % Do nothing if there are no handles
            end
            
            % Get all field names from the handles structure
            fields = fieldnames(figHandlesStruct);
            
            % Loop through each field (e.g., 'fig1', 'ax1', 'jitterSniffMC')
            for i = 1:length(fields)
                % Get the content of the field, which could be a single handle or an array of handles
                fieldValue = figHandlesStruct.(fields{i});
                
                % Loop through each handle in the field value to handle arrays
                for k = 1:numel(fieldValue)
                    h = fieldValue(k); % Get a single handle
                    
                    % Check if this single handle is a valid figure
                    if isgraphics(h) && strcmp(get(h, 'Type'), 'figure')
                        % The generating script will set the 'UserData' property to 'Ready'
                        % when it has finished all processing. This loop waits for that signal.
                        startTime = tic;
                        timeout = 30; % seconds

                        % Wait until UserData is 'Ready' or until timeout
                        while ~isequal(get(h, 'UserData'), 'Ready') && toc(startTime) < timeout
                            % Show message
                            app.update_message('Waiting for figures to be rendered ...');

                            % Flush the graphics queue and then pause briefly to yield
                            % execution to the renderer.
                            if app.ShowFiguresCheckBox.Value == true
                                drawnow;
                                pause(0.1);
                            end
                        end

                        if ~isequal(get(h, 'UserData'), 'Ready')
                            warning('Timed out waiting for figure #%d to become ready.', h.Number);
                        end
                    end
                end
            end
        end

        % Re-enables all UI components after a simulation run
        function enable_ui_components(app)
            % This function is called by a timer to ensure the UI is re-enabled
            % after the main thread has finished its heavy graphics rendering.
            
            app.RunSimulationButton.Enable = 'on';
            app.RunSimulationButton.Text = 'Run Simulation(s)';
            app.RunOptionsPanel.Enable = 'on';
            app.FigTypesListBox.Enable = 'on';
            app.ParameterTree.Enable = 'on';
            app.ParameterEditField.Enable = 'on';
            app.ParameterEditFieldLabel.Enable = 'on';
            app.StringEditField.Enable = 'on';
            app.StringEditFieldLabel.Enable = 'on';
            app.ParameterDropDown.Enable = 'on';
            app.ParameterDropDownLabel.Enable = 'on';
            app.VectorEditField1.Enable = 'on';
            app.VectorEditField2.Enable = 'on';
            app.VectorEditFieldLabel.Enable = 'on';
            app.ParameterCheckBox.Enable = 'on';
            app.EnableSquareWaveInputCheckBox.Enable = 'on';
            app.ToVaryCheckBox.Enable = 'on';
            app.ToVaryEditField.Enable = 'on';
            app.PulseTimesTable.Enable = 'on';
            app.AnalysisResultsTextArea.Enable = 'on';
            app.vIRtRetractionNeuronSampleTracePanel.Enable = 'on';
            app.vIRtProtractionNeuronSampleTracePanel.Enable = 'on';
            app.EffectorTracePanel.Enable = 'on';
        end
        
        % This function performs the graphics-heavy GUI update after a delay
        function begin_gui_update(app, newTestName, analysisText, newHandles, treeState, repetitionMode)
            app.update_message('Waiting for figures to render and updating GUI...');
            
            % Wait for the custom 'Ready' signal, which should now work reliably
            % in combination with the timer delay.
            app.wait_for_figures(newHandles);

            % Perform the graphics-intensive GUI update
            app.update_gui_after_run(newTestName, analysisText, newHandles, treeState);

            % Reset # Reps to 1 in the GUI
            app.NRepetitionsEditField.Value = 1;

            % Bring GUI window to the top
            figure(app.vIRtSimulationsUIFigure);

            % Show update message
            if isempty(repetitionMode)
                app.update_message('Simulation finished. GUI updated!');
            else
                app.update_message([repetitionMode, ' simulation finished successfully! GUI updated using last simulation!']);
            end 
            
            % Re-enable all UI components now that everything is finished
            app.enable_ui_components();
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the expansion map
            app.ExpansionMap = containers.Map('KeyType', 'char', 'ValueType', 'logical');

            % Initialize internal structuress
            app.Handles = struct;
            app.StateOld = struct.empty;
            app.ParamOld = struct.empty;

            % Set starting message
            app.MessageLabel.Text = 'Please load parameters ...';

            % Disable parameter panels until a parameter is selected
            app.SaveParametersAsButton.Visible = 'off';
            app.ExpandTopButton.Visible = 'off';
            app.ExpandAllButton.Visible = 'off';
            app.CollapseAllButton.Visible = 'off';
            app.ParameterEditField.Visible = 'off';
            app.ParameterEditFieldLabel.Visible = 'off';
            app.StringEditField.Visible = 'off';
            app.StringEditFieldLabel.Visible = 'off';
            app.ParameterDropDown.Visible = 'off';
            app.ParameterDropDownLabel.Visible = 'off';
            app.VectorEditField1.Visible = 'off';
            app.VectorEditField2.Visible = 'off';
            app.VectorEditFieldLabel.Visible = 'off';
            app.ParameterCheckBox.Visible = 'off';
            app.EnableSquareWaveInputCheckBox.Visible = 'off';
            app.ToVaryCheckBox.Visible = 'off';
            app.ToVaryEditField.Visible = 'off';
            app.PulseTimesTable.Visible = 'off';
            app.PulseTimesmsLabel.Visible = 'off';

            % Show Figures off is not working, so hide it for now
            app.ShowFiguresCheckBox.Visible = 'off';
            
            % Disable and clear the Test Name field on startup
            app.TestNameEditField.Enable = 'off';
            app.TestNameEditField.Value = '';

            % Disable run options until parameters loaded
            app.RunOptionsPanel.Enable = 'off';
            app.RunSimulationButton.Enable = 'off';
            app.NRepetitionsEditField.Enable = 'off';
            app.FigTypesListBox.Enable = 'off';

            % Disable results panels until a simulation is run
            app.AnalysisResultsTextArea.Visible = 'off';
            app.AnalysisResultsTextAreaLabel.Visible = 'off';
            app.vIRtRetractionNeuronSampleTracePanel.Visible = 'off';
            app.vIRtProtractionNeuronSampleTracePanel.Visible = 'off';
            app.EffectorTracePanel.Visible = 'off';

            % Set default states for the run option checkboxes
            app.SaveOutputCheckBox.Value = true;
            app.CopyScriptCheckBox.Value = false;
            app.RandomizeSeedCheckBox.Value = false;
            app.PlotSampleTracesCheckBox.Value = true;
            app.PlotRasterCheckBox.Value = true;
            app.PlotLogDecrementCheckBox.Value = true;
            app.PlotAmpCorrelationCheckBox.Value = true;
            app.PlotPhaseResponseCheckBox.Value = true;
            app.PlotAggregateOnlyCheckBox.Value = false;
            app.ComputeWhiskAmpCheckBox.Value = true;
            app.ComputeWhiskIntervalCheckBox.Value = true;
            app.ShowFiguresCheckBox.Value = true;
            app.AlwaysNewCheckBox.Value = false;
            app.FigTypesListBox.Value = {'png'};            
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*.mat', 'Select a Parameter File');
            if isequal(fileName, 0)
                return; % User cancelled
            end
            
            try
                paramFilePath = fullfile(pathName, fileName);
                loadedData = load(paramFilePath);
                if isfield(loadedData, 'P')
                    % Set internal parameters structure to loaded parameters structure
                    app.Parameters = loadedData.P;

                    % Check parameters and add any new fields
                    app.Parameters = virt_moore_params('ParamsIn', app.Parameters, ...
                                                        'ParamFilePath', paramFilePath);
                    
                    % Enable the Test Name field now that parameters are loaded
                    app.TestNameEditField.Enable = 'on';

                    % Update the Test Name field's value
                    if isfield(app.Parameters, 'testName')
                        % If testName exists in the struct, use it
                        app.TestNameEditField.Value = app.Parameters.testName;
                    else
                        % Otherwise, throw an error
                        error('Test name field does not exist! Check virt_moore_params.m!');
                    end

                    % Invalidate the previous state
                    app.StateOld = struct.empty;

                    % Hide the parameter edit field and/or check box
                    app.ParameterEditField.Visible = 'off';
                    app.ParameterEditFieldLabel.Visible = 'off';
                    app.ParameterCheckBox.Visible = 'off';
                    app.EnableSquareWaveInputCheckBox.Visible = 'off';
                    app.ToVaryCheckBox.Visible = 'off';
                    app.ToVaryEditField.Visible = 'off';

                    % Reset # Reps to 1
                    app.NRepetitionsEditField.Value = 1;

                    % Hide previous pulse times
                    app.PulseTimesTable.Visible = 'off';
                    app.PulseTimesmsLabel.Visible = 'off';

                    % Reset the expansion map for the new file
                    app.ExpansionMap = containers.Map('KeyType', 'char', 'ValueType', 'logical');
                    
                    % Clear existing tree and populate with new data
                    app.ParameterTree.Children.delete;
                    app.populate_tree(app.ParameterTree, app.Parameters, '');
                    collapse(app.ParameterTree, 'all');

                    % Show tree control buttons now that tree is loaded
                    app.ExpandTopButton.Visible = 'on';
                    app.ExpandAllButton.Visible = 'on';
                    app.CollapseAllButton.Visible = 'on';

                    % Check if the 'enable' parameter exists and update the dedicated checkbox
                    if isfield(app.Parameters, 'SquareInput') && ...
                       isfield(app.Parameters.SquareInput, 'Pb') && ...
                       isfield(app.Parameters.SquareInput.Pb, 'enable')
                   
                        isChecked = app.Parameters.SquareInput.Pb.enable;
                        app.EnableSquareWaveInputCheckBox.Value = isChecked;
                        app.EnableSquareWaveInputCheckBox.Enable = 'on';
                        app.EnableSquareWaveInputCheckBox.Visible = 'on';
                    else
                        app.EnableSquareWaveInputCheckBox.Enable = 'off';
                        app.EnableSquareWaveInputCheckBox.Visible = 'off';
                    end
                    
                    % Show Save Parameters Button
                    app.SaveParametersAsButton.Visible = 'on';

                    % Enable Run button and options
                    app.RunSimulationButton.Enable = 'on';
                    app.NRepetitionsEditField.Enable = 'on';
                    app.RunOptionsPanel.Enable = 'on';
                    app.FigTypesListBox.Enable = 'on';
                    
                    % Update UI
                    app.MessageLabel.Text = 'Parameters loaded!';

                    % Update the pulse times display
                    app.update_pulse_times_display();

                    % Hide old analysis results and plots
                    app.AnalysisResultsTextArea.Visible = 'off';
                    app.AnalysisResultsTextAreaLabel.Visible = 'off';
                    app.vIRtRetractionNeuronSampleTracePanel.Visible = 'off';
                    delete(app.vIRtRetractionNeuronSampleTracePanel.Children);
                    app.vIRtProtractionNeuronSampleTracePanel.Visible = 'off';
                    delete(app.vIRtProtractionNeuronSampleTracePanel.Children);
                    app.EffectorTracePanel.Visible = 'off';
                    delete(app.EffectorTracePanel.Children);
                else
                    uialert(app.vIRtSimulationsUIFigure, 'The selected .mat file does not contain a "P" structure.', 'Loading Error');
                end
            catch E
                uialert(app.vIRtSimulationsUIFigure, ['Error loading file: ' E.message], 'Loading Error');
            end
        end

        % Value changed function: ParameterEditField
        function ParameterEditFieldValueChanged(app, event)
            % Do nothing if there is no node selected
            if ~isvalid(app.SelectedNode) || isempty(app.SelectedNode.NodeData)
                return;
            end
        
            % Get the new value that was entered
            newValue = app.ParameterEditField.Value;
        
            % Get the previous value from the event
            oldValue = event.PreviousValue;
        
            % Get the current node path
            paramPath = app.SelectedNode.NodeData;

            try
                % Call the centralized update method
                app.update_parameter_value(paramPath, newValue, oldValue);
            catch E
                uialert(app.vIRtSimulationsUIFigure, ['Could not update parameter: ' E.message], 'Error');
            end
        end

        % Selection changed function: ParameterTree
        function ParameterTreeSelectionChanged(app, event)
            % Reset the parameter variation state whenever selection changes
            app.reset_to_vary_state(false);

            % Get the selected nodes
            selectedNodes = app.ParameterTree.SelectedNodes;

            % If nothing selected, do nothing
            if isempty(selectedNodes)
                app.SelectedNode = [];
                return;
            end

            % Hide all parameter edit fields initially
            app.ParameterEditField.Visible = 'off';
            app.ParameterEditFieldLabel.Visible = 'off';
            app.ParameterCheckBox.Visible = 'off';
            app.StringEditField.Visible = 'off';
            app.StringEditFieldLabel.Visible = 'off';
            app.ParameterDropDown.Visible = 'off';
            app.ParameterDropDownLabel.Visible = 'off';
            app.VectorEditField1.Visible = 'off';
            app.VectorEditField2.Visible = 'off';
            app.VectorEditFieldLabel.Visible = 'off';
            
            % Reset the message label to default
            if ~isempty(app.Parameters)
                app.MessageLabel.Text = 'You may change this parameter';
            end
           
            % Get the first selected node
            node = selectedNodes(1);
            
            % If the node does not contain a path, do nothing
            %   Otherwise, get the path to the parameter
            if isempty(node.NodeData)
                app.SelectedNode = [];
                return;
            else
                paramPath = node.NodeData;
                app.SelectedNode = node;
            end
                   
            % Do nothing if the square input enable node is selected
            %   as there is already a check box for that
            if strcmp(paramPath, '.SquareInput.Pb.enable')
                return;
            end

            % Add messages for specific parameters
            switch paramPath
            case '.SquareInput.Pb.period1'
                app.MessageLabel.Text = 'Changing Period 1 updates Period Variability 1 based on the current percentage.';
            case '.SquareInput.Pb.period2'
                app.MessageLabel.Text = 'Changing Period 2 updates Period Variability 2 based on the current percentage.';
            case '.SquareInput.Pb.perVar1'
                app.MessageLabel.Text = 'This parameter is linked to Period Variability 1 (%).';
            case '.SquareInput.Pb.perVar2'
                app.MessageLabel.Text = 'This parameter is linked to Period Variability 2 (%).';
            case '.SquareInput.Pb.perVarPerc1'
                app.MessageLabel.Text = 'This parameter is linked to Period Variability 1 (ms).';
            case '.SquareInput.Pb.perVarPerc2'
                app.MessageLabel.Text = 'This parameter is linked to Period Variability 2 (ms).';
            case '.SquareInput.Pb.amplitude'
                app.MessageLabel.Text = 'This parameter is linked to ExtCurrent.Pb.mean';
            case '.ExtCurrent.Pb.mean'
                app.MessageLabel.Text = 'This parameter is linked to SquareInput.Pb.amplitude';
            end

            % Add messages for linked Rr and Rp parameters
            if contains(paramPath, 'Rr') && ~contains(paramPath, 'RpToRr') && ...
                    ~contains(paramPath, 'PbToRr') && ~contains(paramPath, 'RrToFm')
                app.MessageLabel.Text = 'Warning: Changing this Rr param will proportionally change the corresponding Rp param.';
            elseif contains(paramPath, 'Rp') && ~contains(paramPath, 'RrToRp')
                app.MessageLabel.Text = 'Note: Changing this Rp param will NOT affect the corresponding Rr param.';
            end

            try
                % Get the value of the parameter selected internally
                currentValue = app.getfield_recursive(app.Parameters, paramPath);               
                
                % Show 'To Vary' checkbox only for non-Analysis/Plotting numeric parameters
                isSimParam = ~startsWith(paramPath, '.Analysis.') && ~startsWith(paramPath, '.Plotting.');
                isNumeric = isnumeric(currentValue);
                if isSimParam && isNumeric
                    app.ToVaryCheckBox.Visible = 'on';
                end

                % Get descriptive label
                parts = strsplit(paramPath, '.');
                fieldName = parts{end};

                % Construct the full descriptive path label
                %   Note that the first part is always empty
                nParts = numel(parts);
                if nParts == 2 || islogical(currentValue)
                    % Only one part, so use its label and units
                    %   Also do this if it is going to be a checkbox to
                    %   save space
                    [fullLabel, units] = app.get_parameter_info(fieldName, paramPath);
                elseif nParts > 2
                    % Multiple parts, so use units of the last part
                    [~, units] = app.get_parameter_info(fieldName, paramPath);

                    % Construct full label
                    fullLabel = '';
                    for i = 2:numel(parts)
                        if i < numel(parts)
                            % Use the original part to make it short
                            fullLabel = [fullLabel, parts{i}, ': '];
                        else
                            % Use the label for the last part
                            [partLabel, ~] = app.get_parameter_info(parts{i}, strjoin(parts(1:i), '.'));
                            fullLabel = [fullLabel, partLabel];
                        end
                    end
                else
                    % Shouldn't happen
                    error('Code logic error!');
                end

                % Show the corresponding parameter check box or edit field for this parameter
                if islogical(currentValue)
                    app.ParameterCheckBox.Text = fullLabel;
                    app.ParameterCheckBox.Value = currentValue;
                    app.ParameterCheckBox.Visible = 'on';

                elseif isnumeric(currentValue) && isscalar(currentValue)
                    if isempty(units)
                        labelText = sprintf('%s:', fullLabel);
                    else
                        labelText = sprintf('%s (%s):', fullLabel, units);
                    end
                    app.ParameterEditFieldLabel.Text = labelText;
                    app.ParameterEditField.Value = currentValue;
                    app.ParameterEditField.Visible = 'on';
                    app.ParameterEditFieldLabel.Visible = 'on';

                elseif isnumeric(currentValue) && isvector(currentValue) && numel(currentValue) == 2
                    labelText = sprintf('%s (%s):', fullLabel, units);
                    app.VectorEditFieldLabel.Text = labelText;
                    app.VectorEditField1.Value = currentValue(1);
                    app.VectorEditField2.Value = currentValue(2);
                    app.VectorEditField1.Visible = 'on';
                    app.VectorEditField2.Visible = 'on';
                    app.VectorEditFieldLabel.Visible = 'on';
                
                elseif ischar(currentValue) || isstring(currentValue)
                    labelText = sprintf('%s:', fullLabel);

                    % Use a switch on the fieldName for dropdowns
                    switch fieldName
                        case 'rngAlgorithm'
                            app.ParameterDropDown.Items = {'twister', 'simdTwister', 'combRecursive', ...
                                                           'multFibonacci', 'philox', 'threefry', ...
                                                           'v4', 'v5uniform', 'v5normal'};
                        case 'amplitudeDefinition'
                            app.ParameterDropDown.Items = {'peak-to-equilibrium', 'peak-to-avgvalley', 'peak-to-prevalley', 'peak-to-postvalley'};
                        case 'whiskDirForPhase'
                            app.ParameterDropDown.Items = {'retraction', 'protraction'};
                        case {'markerTypePeak', 'markerTypeScatter'}
                            app.ParameterDropDown.Items = {'o', '+', '*', '.', 'x', 's', 'd', '^', 'v', '>', '<', 'p', 'h'};
                        case {'lineStylePeakAmplitude', 'lineStyleBestFit', 'lineStyleThrOrig'}
                            app.ParameterDropDown.Items = {'-', '--', ':', '-.', 'none'};
                        case {'colorPb', 'colorRr', 'colorRp', 'colorFm', ...
                              'colorFirstPeakUsed', 'colorFirstPeakNotUsed', 'colorOtherPeak', ...
                              'colorValley', 'colorPeakAmplitude', 'colorEffectorOverlay', ...
                              'colorBasalCycleShade', 'colorSniffStartWin', 'colorT0', ...
                              'colorT1', 'colorReset', 'colorScatter', 'colorBestFit', 'colorThrOrig', 'colorPreAnalysisShade'}
                            app.ParameterDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', ...
                                                            'black', 'white', 'gray', 'lightgray', 'darkgreen', 'darkblue', ...
                                                            'orange', 'lightorange', 'pink', 'aquamarine', 'default'};
                        otherwise
                            % Default to the string edit field for other strings
                            app.StringEditFieldLabel.Text = labelText;
                            app.StringEditField.Value = currentValue;
                            app.StringEditField.Visible = 'on';
                            app.StringEditFieldLabel.Visible = 'on';
                            return; % Exit to avoid showing the dropdown
                    end
                    
                    % If we are here, it's a dropdown case
                    app.ParameterDropDown.Value = currentValue;
                    app.ParameterDropDown.Visible = 'on';
                    app.ParameterDropDownLabel.Text = labelText;
                    app.ParameterDropDownLabel.Visible = 'on';
                    
                else % This is a struct or an unhandled array type
                    if isstruct(currentValue)
                        app.MessageLabel.Text = 'This is a category, not an editable parameter.';
                    else
                        app.MessageLabel.Text = 'This parameter type cannot be edited here.';
                    end
                end
                
            catch E
                uialert(app.vIRtSimulationsUIFigure, ['Could not retrieve parameter value: ' E.message], 'Error');
            end
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            % --- Initial Checks ---
            if isempty(app.Parameters)
                uialert(app.vIRtSimulationsUIFigure, 'No parameters are loaded.', 'Simulation Error');
                return;
            end
        
            % --- Get and Validate Repetitions ---
            nRepetitions = app.NRepetitionsEditField.Value;
            isPositiveInteger = isscalar(nRepetitions) && floor(nRepetitions) == nRepetitions && nRepetitions >= 1;
        
            % Check # Reps value
            if ~isPositiveInteger
                app.update_message('Error: # Reps must be a positive integer.');
                uialert(app.vIRtSimulationsUIFigure, '# Reps must be set to a positive integer.', 'Input Error');
                return;
            end

            % Determine Repetition Mode
            repetitionMode = ''; % Default for single run
            if nRepetitions > 1
                if app.ToVaryCheckBox.Value && ~isempty(app.rangeToVary)
                    repetitionMode = 'Tuning';
                else % Monte Carlo
                    repetitionMode = 'MonteCarlo';
                    
                    % If Monte Carlo mode, randomize seed must be true to have any effect
                    if ~app.RandomizeSeedCheckBox.Value
                        app.update_message('Warning: Randomize Seed required for Monte Carlo. Enabling.');
                        app.RandomizeSeedCheckBox.Value = true;
                    end
                end
            end

            % If seed randomized, remove any old seed number from the test name to prevent confusion
            if app.RandomizeSeedCheckBox.Value
                app.update_test_name('.seedNumber', []);
            end
        
            % --- Prepare Arguments ---
            % Get base run options
            runOpts = get_run_options(app);

            % Add arguments for specific modes
            if strcmp(repetitionMode, 'Tuning')
                runOpts = [runOpts, {'ParamToVary', app.paramToVaryPath, ...
                                    'RangeToVary', app.rangeToVary, ...
                                    'StrToVary', app.strToChange}];
            end

            % Get the new test name from the edit field
            newTestName = app.TestNameEditField.Value;

            % Create the message handler function
            messageHandler = @(msg) app.update_message(msg);

            % --- Prepare for Simulation ---              
            % Disable UI components
            app.RunSimulationButton.Enable = 'off';
            app.RunSimulationButton.Text = 'Running...';
            app.RunOptionsPanel.Enable = 'off';
            app.FigTypesListBox.Enable = 'off';
            app.ParameterTree.Enable = 'off';
            app.ParameterEditField.Enable = 'off';
            app.ParameterEditFieldLabel.Enable = 'off';
            app.StringEditField.Enable = 'off';
            app.StringEditFieldLabel.Enable = 'off';
            app.ParameterDropDown.Enable = 'off';
            app.ParameterDropDownLabel.Enable = 'off';
            app.VectorEditField1.Enable = 'off';
            app.VectorEditField2.Enable = 'off';
            app.VectorEditFieldLabel.Enable = 'off';
            app.ParameterCheckBox.Enable = 'off';
            app.EnableSquareWaveInputCheckBox.Enable = 'off';
            app.ToVaryCheckBox.Enable = 'off';
            app.ToVaryEditField.Enable = 'off';
            app.PulseTimesTable.Enable = 'off';
            app.AnalysisResultsTextArea.Enable = 'off';
            app.vIRtRetractionNeuronSampleTracePanel.Enable = 'off';
            app.vIRtProtractionNeuronSampleTracePanel.Enable = 'off';
            app.EffectorTracePanel.Enable = 'off';

            % Update the GUI
            drawnow;
            pause(0.1);
            
            % --- Run Simulation(s) ---
            try
                % Start a timer to handle the GUI update after a delay
                tGuiUpdate = timer;
                tGuiUpdate.StartDelay = 1.0; % 1-second delay for rendering
                tGuiUpdate.StopFcn = @(~,~) delete(tGuiUpdate);

                % Save Tree State
                treeState = app.save_tree_state();

                if nRepetitions == 1
                    % --- SINGLE RUN LOGIC ---
                    if exist('virt_moore', 'file') ~= 2
                        error('The function "virt_moore.m" is not in the MATLAB path.');
                    end
        
                    % Call the single simulation function, passing the previous state/params and run options
                    [updatedHandles, updatedP, updatedState, ~, analysisText] = ...
                        virt_moore('Params', app.Parameters, 'ParamOld', app.ParamOld, ...
                                   'StateOld', app.StateOld, 'Handles', app.Handles, ...
                                   'TestName', newTestName, 'MessageHandler', messageHandler, runOpts{:});
                   
                    % Update non-graphical state immediately
                    app.Parameters = updatedP;
                    app.StateOld = updatedState;
                    app.ParamOld = updatedP;
                    app.Handles = updatedHandles;

                    % Set the timer to call the update function with the results
                    tGuiUpdate.TimerFcn = @(~,~) app.begin_gui_update(updatedP.testName, analysisText, updatedHandles, treeState, repetitionMode);
                else
                    % --- MULTIPLE REPETITIONS LOGIC ---
                    if exist('virt_moore_multiple_reps', 'file') ~= 2
                        error('The function "virt_moore_multiple_reps.m" is not in the MATLAB path.');
                    end
        
                    % Extract the seed number from the parameters to pass explicitly
                    seedNum = [];
                    if isfield(app.Parameters, 'seedNumber') && ~isempty(app.Parameters.seedNumber)
                        seedNum = app.Parameters.seedNumber;
                    end

                    % Call the multiple repetitions script, passing options
                    [handlesLast, ~, pAll, stateLast, ~, analysisStringAll] = ...
                        virt_moore_multiple_reps('RepetitionMode', repetitionMode, ...
                                    'Params', app.Parameters, ...
                                    'NRepetitions', nRepetitions, 'FirstSeedNumber', seedNum, ...
                                    'Handles', app.Handles, 'TestName', newTestName, ...
                                    'MessageHandler', messageHandler, runOpts{:});
        
                    % Extract the last run's data from the output arrays
                    ParamLast = pAll(end);
                    StateLast = stateLast;
                    analysisStringLast = analysisStringAll{end};
        
                    % Update GUI state with the LAST run's data
                    app.Parameters = ParamLast;
                    app.StateOld = StateLast;
                    app.ParamOld = ParamLast;
                    app.Handles = handlesLast;

                    % After a parameter sweep, reset the 'To Vary' controls
                    if strcmp(repetitionMode, 'Tuning')
                        % The parameter is still selected, so keep the checkbox visible for a potential new sweep.
                        app.reset_to_vary_state(true);
                    end

                    % Set the timer to call the update function with the last run's results
                    tGuiUpdate.TimerFcn = @(~,~) app.begin_gui_update(ParamLast.testName, analysisStringLast, handlesLast, treeState, repetitionMode);
                end
        
                % Start the timer and update the message
                start(tGuiUpdate);

                % Show completed message
                app.update_message('Simulation finished. Updating GUI plots shortly...');
            catch E
                errorMessage = sprintf('An error occurred: %s', E.message);
                uialert(app.vIRtSimulationsUIFigure, errorMessage, 'Simulation Error');
                app.update_message(errorMessage);
                disp(getReport(E, 'extended', 'hyperlinks','on'));

                % If an error occurs, re-enable the UI immediately
                app.enable_ui_components(); 
            end
        end

        % Button pushed function: SaveParametersAsButton
        function SaveParametersAsButtonPushed(app, event)
            if isempty(app.Parameters)
                uialert(app.vIRtSimulationsUIFigure, 'No parameters to save.', 'Save Error');
                return;
            end
            
            % Generate a unique parameter base from a timestamp
            timeStamp = char(datetime('now', 'Format', 'yyyyMMdd''T''HHmm'));
            paramFileBase = ['virt_moore_Params_', timeStamp];

            % Add test name if available
            if isfield(app.Parameters, 'testName')
                paramFileBase = [paramFileBase, '_', app.Parameters.testName];
            end

            % Create mat file name
            paramFileName = [paramFileBase, '.mat'];

            % Save to path
            [file, path] = uiputfile('*.mat', 'Save Parameters As', paramFileName);
            
            if isequal(file, 0)
                return; % User cancelled
            end
            
            try
                P = app.Parameters;
                save(fullfile(path, file), 'P');
                fprintf('Parameters saved to %s\n', fullfile(path, file));
            catch E
                uialert(app.vIRtSimulationsUIFigure, ['Could not save file: ' E.message], 'Save Error');
            end
        end

        % Value changed function: EnableSquareWaveInputCheckBox
        function EnableSquareWaveInputCheckBoxValueChanged(app, event)
            % Get new value
            newValue = app.EnableSquareWaveInputCheckBox.Value;

            % Revert the click if no parameters loaded
            if isempty(app.Parameters)
                uialert(app.vIRtSimulationsUIFigure, 'Load parameters before changing this setting.', 'Warning');
                app.EnableSquareWaveInputCheckBox.Value = ~newValue;
                return;
            end
            
            % Invalidate state because this is a simulation parameter
            app.StateOld = struct.empty;
            app.MessageLabel.Text = 'Simulation parameter changed. Re-run required.';

            % Update internally
            app.Parameters.SquareInput.Pb.enable = newValue;

            % Update the tree on the GUI
            if newValue, valStr = 'true'; else, valStr = 'false'; end
            app.update_tree_node_text('.SquareInput.Pb.enable', 'enable', valStr);

            % Set toRecompute flag to true and hide previous pulse times
            if isfield(app.Parameters.SquareInput.Pb, 'toRecompute')
                % Set toRecompute flag to true
                app.Parameters.SquareInput.Pb.toRecompute = true;

                % Hide previous pulse times
                app.invalidate_pulse_times_display();

                % Update the tree on the GUI
                app.update_node_by_path('.SquareInput.Pb.toRecompute');
            end

            % Append the change to the test name
            app.update_test_name('.SquareInput.Pb.enable', newValue);
        end

        % Value changed function: ParameterCheckBox
        function ParameterCheckBoxValueChanged(app, event)
            newValue = app.ParameterCheckBox.Value;
            
            if isempty(app.SelectedNode) || isempty(app.SelectedNode.NodeData)
                return;
            end
            
            paramPath = app.SelectedNode.NodeData;

            % Invalidate state if a non-analysis parameter is changed
            if ~startsWith(paramPath, '.Analysis.')
                app.StateOld = struct.empty;
                app.MessageLabel.Text = 'Simulation parameter changed. Re-run required.';
            end

            try
                app.Parameters = app.setfield_recursive(app.Parameters, paramPath, newValue);

                % Get the current field name
                parts = strsplit(paramPath, '.');
                fieldName = parts{end};

                % Pass the full paramPath for context-aware labeling
                [label, ~] = app.get_parameter_info(fieldName, paramPath);

                if newValue, valStr = 'true'; else, valStr = 'false'; end
                app.SelectedNode.Text = sprintf('%s: %s', label, valStr);
                
                % Append the change to the test name
                app.update_test_name(paramPath, newValue);                
            catch E
                uialert(app.vIRtSimulationsUIFigure, ['Could not update parameter: ' E.message], 'Error');
            end
        end

        % Button pushed function: ExpandAllButton
        function ExpandAllButtonPushed(app, event)
            % Use the direct, robust method to expand all nodes
            expand(app.ParameterTree, 'all');
            
            % Now, find all nodes to update the expansion map
            allNodes = findall(app.ParameterTree, 'Type', 'uitreenode');
            for i = 1:numel(allNodes)
                if ~isempty(allNodes(i).NodeData)
                    app.ExpansionMap(allNodes(i).NodeData) = true;
                end
            end
        end

        % Button pushed function: CollapseAllButton
        function CollapseAllButtonPushed(app, event)
            collapse(app.ParameterTree, 'all');
            app.ExpansionMap = containers.Map('KeyType', 'char', 'ValueType', 'logical');
        end

        % Node expanded function: ParameterTree
        function ParameterTreeNodeExpanded(app, event)
            node = event.Node;
            if ~isempty(node.NodeData)
                app.ExpansionMap(node.NodeData) = true;
            end
        end

        % Node collapsed function: ParameterTree
        function ParameterTreeNodeCollapsed(app, event)
            node = event.Node;
            if ~isempty(node.NodeData)
                app.ExpansionMap(node.NodeData) = false;
            end
        end

        % Button pushed function: ExpandTopButton
        function ExpandTopButtonPushed(app, event)
            % Expand the top level nodes in the tree
            expand(app.ParameterTree);
            
            % Update the expansion map for the visible nodes
            topLevelNodes = app.ParameterTree.Children;
            for i = 1:numel(topLevelNodes)
                if ~isempty(topLevelNodes(i).NodeData)
                    app.ExpansionMap(topLevelNodes(i).NodeData) = true;
                end
            end        
        end

        % Value changed function: StringEditField
        function StringEditFieldValueChanged(app, event)
            if ~isvalid(app.SelectedNode) || isempty(app.SelectedNode.NodeData)
                return;
            end
            
            % Get the value and node name
            newValue = app.StringEditField.Value;
            paramPath = app.SelectedNode.NodeData;
            
            % Invalidate state if a non-analysis parameter is changed
            if ~startsWith(paramPath, '.Analysis.')
                app.StateOld = struct.empty;
                app.MessageLabel.Text = 'Simulation parameter changed. Re-run required.';
            end

            try
                % Update internally and in the tree
                app.Parameters = app.setfield_recursive(app.Parameters, paramPath, newValue);
                app.update_node_by_path(paramPath);

                % Append the change to the test name
                app.update_test_name(paramPath, newValue);
            catch E
                uialert(app.vIRtSimulationsUIFigure, ['Could not update parameter: ' E.message], 'Error');
            end
        end

        % Value changed function: VectorEditField1, VectorEditField2
        function VectorEditFieldValueChanged(app, event)
            if ~isvalid(app.SelectedNode) || isempty(app.SelectedNode.NodeData)
                return;
            end
            
            % Combine values from both fields to form the new vector
            newValue = [app.VectorEditField1.Value, app.VectorEditField2.Value];
            paramPath = app.SelectedNode.NodeData;
            
            % Invalidate state if a non-analysis parameter is changed
            if ~startsWith(paramPath, '.Analysis.')
                app.StateOld = struct.empty;
                app.MessageLabel.Text = 'Simulation parameter changed. Re-run required.';
            end

            try
                % Update internally and in the tree
                app.Parameters = app.setfield_recursive(app.Parameters, paramPath, newValue);
                app.update_node_by_path(paramPath);

                % Append the change to the test name
                app.update_test_name(paramPath, newValue);
            catch E
                uialert(app.vIRtSimulationsUIFigure, ['Could not update parameter: ' E.message], 'Error');
            end
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            if ~isvalid(app.SelectedNode) || isempty(app.SelectedNode.NodeData)
                return;
            end
            
            % Get the value and node name
            newValue = app.ParameterDropDown.Value;
            paramPath = app.SelectedNode.NodeData;
            
            try
                % Update internally and in the tree
                app.Parameters = app.setfield_recursive(app.Parameters, paramPath, newValue);
                app.update_node_by_path(paramPath);

                % Append the change to the test name
                app.update_test_name(paramPath, newValue);
            catch E
                uialert(app.vIRtSimulationsUIFigure, ['Could not update parameter: ' E.message], 'Error');
            end
        end

        % Value changed function: TestNameEditField
        function TestNameEditFieldValueChanged(app, event)
            % Do nothing if no parameters are loaded yet
            if isempty(app.Parameters)
                return;
            end
            
            % Get the new value from the edit field
            newValue = app.TestNameEditField.Value;
            
            % Update the testName field in the internal Parameters struct
            app.Parameters.testName = newValue;
            
            % Optionally, provide feedback to the user
            app.update_message('Test name updated.');
        end

        % Value changed function: NRepetitionsEditField
        function NRepetitionsEditFieldValueChanged(app, event)
            % Do nothing if no parameters are loaded yet
            if isempty(app.Parameters)
                return;
            end
            
            % Get the new number of repetitions from the edit field
            newValue = app.NRepetitionsEditField.Value;
            
            % Check if the new number is a positive integer
            isPositiveInteger = isscalar(newValue) && floor(newValue) == newValue && newValue >= 1;
            
            if ~isPositiveInteger
                % If not, revert to the old value and display a message
                previousValue = event.PreviousValue;
                app.NRepetitionsEditField.Value = previousValue;
                
                % Provide feedback to the user
                errorMessage = '# Reps must be a positive integer.';
                app.update_message(['Error: ', errorMessage]);
                uialert(app.vIRtSimulationsUIFigure, errorMessage, 'Input Error');
                return; % Stop further processing
            end

            % Update the test name to reflect the new number of repetitions
            if newValue == 1
                % If number of repetitions is 1, remove from test name
                app.update_test_name('nReps', []);
            else
                app.update_test_name('nReps', newValue);
            end
            
            % Provide feedback to the user in the message area
            app.update_message('Test name updated for # Reps.');
        end

        % Value changed function: ToVaryCheckBox
        function ToVaryCheckBoxValueChanged(app, event)
            % If no parameter is selected, reset everything
            if isempty(app.SelectedNode) || isempty(app.SelectedNode.NodeData)
                app.update_message('A simulation parameter must be selected to be varied!');
                app.reset_to_vary_state(false);
                return;
            end

            % Get whether checkbox is checked
            isChecked = app.ToVaryCheckBox.Value;
            
            % Perform action
            if isChecked
                % Show the edit field
                app.ToVaryEditField.Visible = 'on';

                % Populate the edit field with the current value of the selected parameter
                currentValue = app.getfield_recursive(app.Parameters, app.SelectedNode.NodeData);
                app.ToVaryEditField.Value = mat2str(currentValue);

                % Store the path to the selected parameter
                app.paramToVaryPath = app.SelectedNode.NodeData;
            else
                % If unchecked, reset everything but keep check box shown
                app.reset_to_vary_state(true);
            end
        end

        % Value changed function: ToVaryEditField
        function ToVaryEditFieldValueChanged(app, event)
            if ~app.ToVaryCheckBox.Value
                app.update_message('To Vary Check Box must be checked to edit this field!');
                return; % Do nothing if the box isn't checked
            end

            % Get the user input
            expression = app.ToVaryEditField.Value;

            % Get the current value of the parameter of interest
            currentValue = app.getfield_recursive(app.Parameters, app.paramToVaryPath);
            
            try
                % Attempt to evaluate the expression entered by the user
                result = evalin('base', expression);
                
                % Validate the result
                if ~isnumeric(result) || ~isvector(result)
                    error('Input must evaluate to a 1-dimensional numeric vector.');
                end
                
                % Update parameter of interest or internal rangeToVary
                if isscalar(result)
                    % If only one value, just update the parameter directly

                    % Get the path to the selected parameter
                    paramPath = app.paramToVaryPath;

                    % Update the parameter internally and in the tree
                    app.update_parameter_value(paramPath, result, currentValue);

                    % Update the main edit field to be in sync
                    app.ParameterEditField.Value = result;

                    % Uncheck To Vary Checkbox after updating but keep it shown
                    app.reset_to_vary_state(true);

                    % Show message
                    app.update_message('Single value entered. Updating parameter directly.');
                else
                    % If more than one value, create parameter sweep

                    % Update internal state
                    app.rangeToVary = result;

                    % Update # Reps field and disable it
                    nReps = numel(result);
                    app.NRepetitionsEditField.Value = nReps;
                    app.NRepetitionsEditField.Enable = 'off';

                    % Update the test name to reflect the new sweep and remove # Reps
                    app.update_test_name(app.paramToVaryPath, expression);
                    app.update_test_name('nReps', []);
                    
                    % Show message
                    app.update_message(['Parameter will be varied over ', num2str(nReps), ' values.']);
                end
                
            catch E
                % If evaluation fails, show an error and revert the field
                app.update_message(['Error: ', E.message]);
                uialert(app.vIRtSimulationsUIFigure, 'Invalid input. Entry must be a valid MATLAB expression for a 1-dimensional numeric vector (e.g., ''1:5'' or ''[1, 5, 10]'').', 'Input Error');
                app.ToVaryEditField.Value = mat2str(currentValue); % Revert to old value
                app.rangeToVary = []; % Clear invalid range
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.vIRtSimulationsUIFigure = uifigure('Visible', 'off');
            app.vIRtSimulationsUIFigure.Name = 'vIRt Simulations';
            app.ParameterEditFieldLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.ParameterEditFieldLabel.Text = 'Parameter';
            app.TestNameEditFieldLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.TestNameEditFieldLabel.Text = 'Test Name';
            app.AnalysisResultsTextAreaLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.AnalysisResultsTextAreaLabel.Text = 'Analysis Results';
            app.EffectorTracePanel = uipanel(app.vIRtSimulationsUIFigure);
            app.EffectorTracePanel.Title = 'Effector Trace';
            app.vIRtProtractionNeuronSampleTracePanel = uipanel(app.vIRtSimulationsUIFigure);
            app.vIRtProtractionNeuronSampleTracePanel.Title = 'vIRt Protraction Neuron Sample Trace';
            app.vIRtRetractionNeuronSampleTracePanel = uipanel(app.vIRtSimulationsUIFigure);
            app.vIRtRetractionNeuronSampleTracePanel.Title = 'vIRt Retraction Neuron Sample Trace';
            app.AnalysisResultsTextArea = uitextarea(app.vIRtSimulationsUIFigure);
            app.PulseTimesTable = uitable(app.vIRtSimulationsUIFigure);
            app.PulseTimesTable.ColumnName = {'#'; 'Start'; 'End'};
            app.PulseTimesTable.RowName = {};
            app.PulseTimesmsLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.PulseTimesmsLabel.Text = 'Pulse Times (ms)';
            app.SaveParametersAsButton = uibutton(app.vIRtSimulationsUIFigure, 'push');
            app.SaveParametersAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersAsButtonPushed, true);
            app.SaveParametersAsButton.Text = 'Save Parameters As ...';
            app.NRepetitionsEditField = uieditfield(app.vIRtSimulationsUIFigure, 'numeric');
            app.NRepetitionsEditField.ValueDisplayFormat = '%.0f';
            app.NRepetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @NRepetitionsEditFieldValueChanged, true);
            app.NRepetitionsEditField.Enable = 'off';
            app.NRepetitionsEditField.Value = 1;
            app.RunSimulationButton = uibutton(app.vIRtSimulationsUIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Enable = 'off';
            app.RunSimulationButton.Text = 'Run Simulation(s)';
            app.RunOptionsPanel = uipanel(app.vIRtSimulationsUIFigure);
            app.RunOptionsPanel.Title = 'Run Options';
            app.AlwaysNewCheckBox = uicheckbox(app.RunOptionsPanel);
            app.AlwaysNewCheckBox.Text = 'Always New';
            app.PlotAggregateOnlyCheckBox = uicheckbox(app.RunOptionsPanel);
            app.PlotAggregateOnlyCheckBox.Text = 'Plot Aggregate Only';
            app.ComputeWhiskIntervalCheckBox = uicheckbox(app.RunOptionsPanel);
            app.ComputeWhiskIntervalCheckBox.Text = 'Compute Whisk Interval';
            app.ComputeWhiskAmpCheckBox = uicheckbox(app.RunOptionsPanel);
            app.ComputeWhiskAmpCheckBox.Text = 'Compute Whisk Amp';
            app.PlotPhaseResponseCheckBox = uicheckbox(app.RunOptionsPanel);
            app.PlotPhaseResponseCheckBox.Text = 'Plot Phase Response';
            app.PlotAmpCorrelationCheckBox = uicheckbox(app.RunOptionsPanel);
            app.PlotAmpCorrelationCheckBox.Text = 'Plot Amp Correlation';
            app.PlotLogDecrementCheckBox = uicheckbox(app.RunOptionsPanel);
            app.PlotLogDecrementCheckBox.Text = 'Plot Log Decrement';
            app.PlotRasterCheckBox = uicheckbox(app.RunOptionsPanel);
            app.PlotRasterCheckBox.Text = 'Plot Raster';
            app.PlotSampleTracesCheckBox = uicheckbox(app.RunOptionsPanel);
            app.PlotSampleTracesCheckBox.Text = 'Plot Sample Traces';
            app.RandomizeSeedCheckBox = uicheckbox(app.RunOptionsPanel);
            app.RandomizeSeedCheckBox.Text = 'Randomize Seed';
            app.CopyScriptCheckBox = uicheckbox(app.RunOptionsPanel);
            app.CopyScriptCheckBox.Text = 'Copy Script';
            app.SaveOutputCheckBox = uicheckbox(app.RunOptionsPanel);
            app.SaveOutputCheckBox.Text = 'Save Output';
            app.ShowFiguresCheckBox = uicheckbox(app.RunOptionsPanel);
            app.ShowFiguresCheckBox.Text = 'Show Figures';
            app.FigTypesListBox = uilistbox(app.vIRtSimulationsUIFigure);
            app.FigTypesListBox.Items = {'png', 'fig', 'epsc'};
            app.FigTypesListBox.Multiselect = 'on';
            app.FigTypesListBox.Value = {'png'};
            app.TestNameEditField = uieditfield(app.vIRtSimulationsUIFigure, 'text');
            app.TestNameEditField.ValueChangedFcn = createCallbackFcn(app, @TestNameEditFieldValueChanged, true);
            app.ToVaryEditField = uieditfield(app.vIRtSimulationsUIFigure, 'text');
            app.ToVaryEditField.ValueChangedFcn = createCallbackFcn(app, @ToVaryEditFieldValueChanged, true);
            app.ToVaryCheckBox = uicheckbox(app.vIRtSimulationsUIFigure);
            app.ToVaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToVaryCheckBoxValueChanged, true);
            app.ToVaryCheckBox.Text = 'To Vary';
            app.EnableSquareWaveInputCheckBox = uicheckbox(app.vIRtSimulationsUIFigure);
            app.EnableSquareWaveInputCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSquareWaveInputCheckBoxValueChanged, true);
            app.EnableSquareWaveInputCheckBox.Text = 'Enable Square Wave Input';
            app.ParameterCheckBox = uicheckbox(app.vIRtSimulationsUIFigure);
            app.ParameterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterCheckBoxValueChanged, true);
            app.ParameterCheckBox.Text = 'Parameter Check Box';
            app.VectorEditField2 = uieditfield(app.vIRtSimulationsUIFigure, 'numeric');
            app.VectorEditField2.ValueChangedFcn = createCallbackFcn(app, @VectorEditFieldValueChanged, true);
            app.VectorEditField1 = uieditfield(app.vIRtSimulationsUIFigure, 'numeric');
            app.VectorEditField1.ValueChangedFcn = createCallbackFcn(app, @VectorEditFieldValueChanged, true);
            app.StringEditFieldLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.StringEditFieldLabel.Text = 'String Edit Field';
            app.StringEditField = uieditfield(app.vIRtSimulationsUIFigure, 'text');
            app.StringEditField.ValueChangedFcn = createCallbackFcn(app, @StringEditFieldValueChanged, true);
            app.ParameterDropDown = uidropdown(app.vIRtSimulationsUIFigure);
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterEditField = uieditfield(app.vIRtSimulationsUIFigure, 'numeric');
            app.ParameterEditField.ValueChangedFcn = createCallbackFcn(app, @ParameterEditFieldValueChanged, true);
            app.MessageLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.MessageLabel.Text = 'Message';
            app.ParameterTree = uitree(app.vIRtSimulationsUIFigure);
            app.ParameterTree.SelectionChangedFcn = createCallbackFcn(app, @ParameterTreeSelectionChanged, true);
            app.ParameterTree.NodeExpandedFcn = createCallbackFcn(app, @ParameterTreeNodeExpanded, true);
            app.ParameterTree.NodeCollapsedFcn = createCallbackFcn(app, @ParameterTreeNodeCollapsed, true);
            app.CollapseAllButton = uibutton(app.vIRtSimulationsUIFigure, 'push');
            app.CollapseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CollapseAllButtonPushed, true);
            app.CollapseAllButton.Text = 'Collapse All';
            app.ExpandTopButton = uibutton(app.vIRtSimulationsUIFigure, 'push');
            app.ExpandTopButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandTopButtonPushed, true);
            app.ExpandTopButton.Text = 'Expand Top';
            app.ExpandAllButton = uibutton(app.vIRtSimulationsUIFigure, 'push');
            app.ExpandAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandAllButtonPushed, true);
            app.ExpandAllButton.Text = 'Expand All';
            app.LoadParametersButton = uibutton(app.vIRtSimulationsUIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.GraphicalUserInterfaceforvIRtSimulationsusingAdaptiveExponentialIntegrateandFireneuronsLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.GraphicalUserInterfaceforvIRtSimulationsusingAdaptiveExponentialIntegrateandFireneuronsLabel.Text = 'Graphical User Interface for vIRt Simulations using Adaptive Exponential Integrate-and-Fire neurons';
            app.VectorEditFieldLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.VectorEditFieldLabel.Text = 'Vector Edit Field';
            app.ParameterDropDownLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.ParameterDropDownLabel.Text = 'Parameter Drop Down';
            app.RepsLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.RepsLabel.Text = '# Reps';
            app.FigTypesListBoxLabel = uilabel(app.vIRtSimulationsUIFigure);
            app.FigTypesListBoxLabel.Text = 'FigTypes';
            % Show the figure after all components are created
            app.vIRtSimulationsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = virt_moore_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.vIRtSimulationsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.vIRtSimulationsUIFigure)
        end
    end
end